classdef NOAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        aplicacion1                  matlab.ui.Figure
        BrowseButton                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        DecodeButton                 matlab.ui.control.Button
        IngenieraentecnologasdelatelecomunicacinLabel  matlab.ui.control.Label
        SelectwavfileEditFieldLabel  matlab.ui.control.Label
        SelectwavfileEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        path_archivo;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseButton, SelectwavfileEditField
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.wav'); %open a wav file
            if isequal(file,0)
                app.SelectwavfileEditField.Value = 'No file';
            else
                app.SelectwavfileEditField.Value = file;
                app.path_archivo = strcat(convertCharsToStrings(path),convertCharsToStrings(file));
%                 msgbox(strcat('Este es el path: ',app.path_archivo));
            end
            figure(app.aplicacion1);
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            if ~strcmp(app.SelectwavfileEditField.Value, 'No file') && ~isempty(app.SelectwavfileEditField.Value) 
                fig = figure(app.aplicacion1);
                msg = 'Processing ...';
                uialert(fig,msg,'Info Message','Icon','success');
                uiwait(fig,2);
                Procesado(app.path_archivo)
                delete(app)
            else
                fig = figure(app.aplicacion1);
                message{1} = 'Select a correct wav file';
                message{2} = 'Push Browse button';
                title = 'Error in wav file';
                uialert(fig,message,title);
            end
        end

        % Callback function
        function SelectwavfileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            fig = uifigure;
            ef = uieditfield(fig);
            ef.Value = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.aplicacion1 = uifigure('Visible', 'off');
            app.aplicacion1.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.aplicacion1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = {'Browse'; ''};
            app.Image = uiimage(app.aplicacion1);
            app.Image.ImageSource = 'base3.jpg';
            app.DecodeButton = uibutton(app.aplicacion1, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.IngenieraentecnologasdelatelecomunicacinLabel = uilabel(app.aplicacion1);
            app.IngenieraentecnologasdelatelecomunicacinLabel.Text = {'Ingeniería en tecnologías de la telecomunicación'; 'App by Rocío Navarro Villarino'; 'Trabajo Fin de Grado'};
            app.SelectwavfileEditFieldLabel = uilabel(app.aplicacion1);
            app.SelectwavfileEditFieldLabel.Text = 'Select wav file';
            app.SelectwavfileEditField = uieditfield(app.aplicacion1, 'text');
            app.SelectwavfileEditField.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.SelectwavfileEditField.Editable = 'off';
            % Show the figure after all components are created
            app.aplicacion1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NOAApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.aplicacion1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.aplicacion1)
        end
    end
end