classdef Procesado < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        aplicacion2                matlab.ui.Figure
        SelectwavfileButton        matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        FalseColorTab              matlab.ui.container.Tab
        FalseColorLabel            matlab.ui.control.Label
        FalseColorSwitch           matlab.ui.control.Switch
        CloudsSliderLabel          matlab.ui.control.Label
        CloudsSlider               matlab.ui.control.Slider
        EditFieldClouds            matlab.ui.control.NumericEditField
        FloraSliderLabel           matlab.ui.control.Label
        FloraSlider                matlab.ui.control.Slider
        EditFieldFlora             matlab.ui.control.NumericEditField
        WaterSliderLabel           matlab.ui.control.Label
        WaterSlider                matlab.ui.control.Slider
        EditFieldWater             matlab.ui.control.NumericEditField
        ResetvaluesButton          matlab.ui.control.Button
        ProcessButton              matlab.ui.control.Button
        ContrastSwitchLabel        matlab.ui.control.Label
        ContrastSwitch             matlab.ui.control.Switch
        CropImageTab               matlab.ui.container.Tab
        CropBlackWhiteImageButton  matlab.ui.control.Button
        CropColorImageButton       matlab.ui.control.Button
        CropInformationButton      matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        archivoWav
        imagen
        imagenColor
        imagenContraste
        imagen_Crop
        imagenSinTel
        clouds_opt = 81
        flora_opt = 69
        water_opt = 37
        clouds = 81
        flora = 69
        water = 37
    end
    
    methods (Access = private)
        
        function PrintColorImage(app)
            app.imagenColor = color(app.imagen, app.clouds, app.flora, app.water);
            Visualizacion(app.imagenColor)
%             imshow(app.imagenColor, 'Parent', app.UIAxes);
        end
    end
    
    methods (Access = public)
        
        function Habilitar(app)
            app.CloudsSlider.Enable = 'on';
            app.FloraSlider.Enable = 'on';
            app.WaterSlider.Enable = 'on';
            app.EditFieldClouds.Enable = 'on';
            app.EditFieldFlora.Enable = 'on';
            app.EditFieldWater.Enable = 'on';
            app.CloudsSliderLabel.Enable = 'on';
            app.FloraSliderLabel.Enable = 'on';
            app.WaterSliderLabel.Enable = 'on';
            app.ResetvaluesButton.Enable = 'on';
            app.ProcessButton.Enable = 'on';
        end
    end
    
    methods (Access = public)
        
        function Deshabilitar(app)
            app.CloudsSlider.Enable = 'off';
            app.FloraSlider.Enable = 'off';
            app.WaterSlider.Enable = 'off';
            app.EditFieldClouds.Enable = 'off';
            app.EditFieldFlora.Enable = 'off';
            app.EditFieldWater.Enable = 'off';
            app.CloudsSliderLabel.Enable = 'off';
            app.FloraSliderLabel.Enable = 'off';
            app.WaterSliderLabel.Enable = 'off';
            app.ResetvaluesButton.Enable = 'off';
            app.ProcessButton.Enable = 'off';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, file)
          % Update UI with input values
            app.archivoWav = file;
%             msgbox(strcat('Este es el path: ',app.archivoWav));
            app.imagen = decod(app.archivoWav);
            
%             %Give resolution for your previous screen:
%             old_screen=[1920,1080];
%             %Collect information about new screen:
%             set(0,'units','pixels');
%             Pix_SS = get(0,'screensize');
%             %Assign coefficient of reduction/increase:
%             k_hor=Pix_SS(3)/old_screen(1);
%             k_vert=Pix_SS(4)/old_screen(2);
%             %Get size of your original application UI:
%             uisize = app.aplicacion2.Position;
%             screenWidth = uisize(3);
%             screenHeight = uisize(4);
%             left = uisize(1);
%             bottom = uisize(2);
%             %Assign new size for the width and height:
%             width = screenWidth*k_hor;
%             height = screenHeight*k_vert;
%             drawnow;
%             %Change UI element size to new scaled size:
%             app.aplicacion2.Position(3:4) = [width height];
            
            Visualizacion(app.imagen);
%             imshow(app.imagen, 'Parent', app.UIAxes);

            
        end

        % Button pushed function: SelectwavfileButton
        function SelectwavfileButtonPushed(app, event)
            delete(app)
            NOAApp
        end

        % Value changed function: CloudsSlider
        function CloudsSliderValueChanged(app, event)
            value = app.CloudsSlider.Value;
            app.EditFieldClouds.Value = value;
            app.clouds = value;
        end

        % Value changed function: FloraSlider
        function FloraSliderValueChanged(app, event)
            value = app.FloraSlider.Value;
            app.EditFieldFlora.Value = value;
            app.flora = value;
        end

        % Value changed function: WaterSlider
        function WaterSliderValueChanged(app, event)
            value = app.WaterSlider.Value;
            app.EditFieldWater.Value = value;
            app.water = value;
        end

        % Value changed function: FalseColorSwitch
        function FalseColorSwitchValueChanged(app, event)
            value = app.FalseColorSwitch.Value;
            if strcmpi(value,'On') == 1
                msg = 'Processing False Color ...';
                uialert(app.aplicacion2,msg,'Info Message','Icon','success');
                uiwait(app.aplicacion2,2);
                PrintColorImage(app)
                Habilitar(app)
            else
                Visualizacion(app.imagen)
                Deshabilitar(app)
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            msg = 'Processing options colors ...';
            uialert(app.aplicacion2,msg,'Info Message','Icon','success');
            uiwait(app.aplicacion2,2);
            PrintColorImage(app)
        end

        % Button pushed function: ResetvaluesButton
        function ResetvaluesButtonPushed(app, event)
            app.clouds = app.clouds_opt;
            app.EditFieldClouds.Value = app.clouds;
            app.CloudsSlider.Value = app.clouds;
            app.flora = app.flora_opt;
            app.EditFieldFlora.Value = app.flora;
            app.FloraSlider.Value = app.flora;
            app.water = app.water_opt;
            app.EditFieldWater.Value = app.water;
            app.WaterSlider.Value = app.water;
            
%             PrintColorImage(app)
        end

        % Button pushed function: CropBlackWhiteImageButton
        function CropBlackWhiteImageButtonPushed(app, event)
            [app.imagen_Crop, ~] = imcrop(app.imagen);
            Visualizacion(app.imagen_Crop);
        end

        % Button pushed function: CropColorImageButton
        function CropColorImageButtonPushed(app, event)
            app.imagenColor = color(app.imagen, app.clouds, app.flora, app.water);
            [app.imagen_Crop, ~] = imcrop(app.imagenColor);
            Visualizacion(app.imagen_Crop);
        end

        % Button pushed function: CropInformationButton
        function CropInformationButtonPushed(app, event)
            app.imagenSinTel = sin_telemetria(app.imagen);
            Visualizacion(app.imagenSinTel);
        end

        % Value changed function: ContrastSwitch
        function ContrastSwitchValueChanged(app, event)
            value = app.ContrastSwitch.Value;
            if strcmpi(value,'On') == 1
                msg = 'Processing contrast ...';
                uialert(app.aplicacion2,msg,'Info Message','Icon','success');
                uiwait(app.aplicacion2,2);
                app.imagenContraste = contraste(app.imagen);
                Visualizacion(app.imagenContraste)
%                 imshow(app.imagenContraste, 'Parent', app.UIAxes);
            else
                Visualizacion(app.imagen)
%                 imshow(app.imagen, 'Parent', app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.aplicacion2 = uifigure('Visible', 'off');
            app.aplicacion2.Name = 'MATLAB App';
            app.SelectwavfileButton = uibutton(app.aplicacion2, 'push');
            app.SelectwavfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectwavfileButtonPushed, true);
            app.SelectwavfileButton.Icon = 'flecha_atras.jpg';
            app.SelectwavfileButton.Text = 'Select wav file';
            app.TabGroup = uitabgroup(app.aplicacion2);
            app.FalseColorTab = uitab(app.TabGroup);
            app.FalseColorTab.Title = 'False Color';
            app.FalseColorLabel = uilabel(app.FalseColorTab);
            app.FalseColorLabel.Text = 'False Color: ';
            app.FalseColorSwitch = uiswitch(app.FalseColorTab, 'slider');
            app.FalseColorSwitch.ValueChangedFcn = createCallbackFcn(app, @FalseColorSwitchValueChanged, true);
            app.CloudsSliderLabel = uilabel(app.FalseColorTab);
            app.CloudsSliderLabel.Enable = 'off';
            app.CloudsSliderLabel.Text = 'Clouds';
            app.CloudsSlider = uislider(app.FalseColorTab);
            app.CloudsSlider.Limits = [1 255];
            app.CloudsSlider.MajorTicks = [1 51 101 151 201 255];
            app.CloudsSlider.ValueChangedFcn = createCallbackFcn(app, @CloudsSliderValueChanged, true);
            app.CloudsSlider.Enable = 'off';
            app.CloudsSlider.Value = 81;
            app.EditFieldClouds = uieditfield(app.FalseColorTab, 'numeric');
            app.EditFieldClouds.Limits = [1 255];
            app.EditFieldClouds.Editable = 'off';
            app.EditFieldClouds.Enable = 'off';
            app.EditFieldClouds.Value = 81;
            app.FloraSliderLabel = uilabel(app.FalseColorTab);
            app.FloraSliderLabel.Enable = 'off';
            app.FloraSliderLabel.Text = 'Flora';
            app.FloraSlider = uislider(app.FalseColorTab);
            app.FloraSlider.Limits = [1 255];
            app.FloraSlider.MajorTicks = [1 51 101 151 201 255];
            app.FloraSlider.ValueChangedFcn = createCallbackFcn(app, @FloraSliderValueChanged, true);
            app.FloraSlider.Enable = 'off';
            app.FloraSlider.Value = 69;
            app.EditFieldFlora = uieditfield(app.FalseColorTab, 'numeric');
            app.EditFieldFlora.Limits = [1 255];
            app.EditFieldFlora.Editable = 'off';
            app.EditFieldFlora.Enable = 'off';
            app.EditFieldFlora.Value = 69;
            app.WaterSliderLabel = uilabel(app.FalseColorTab);
            app.WaterSliderLabel.Enable = 'off';
            app.WaterSliderLabel.Text = 'Water';
            app.WaterSlider = uislider(app.FalseColorTab);
            app.WaterSlider.Limits = [1 255];
            app.WaterSlider.MajorTicks = [1 51 101 151 201 255];
            app.WaterSlider.ValueChangedFcn = createCallbackFcn(app, @WaterSliderValueChanged, true);
            app.WaterSlider.Enable = 'off';
            app.WaterSlider.Value = 37;
            app.EditFieldWater = uieditfield(app.FalseColorTab, 'numeric');
            app.EditFieldWater.Limits = [1 255];
            app.EditFieldWater.Editable = 'off';
            app.EditFieldWater.Enable = 'off';
            app.EditFieldWater.Value = 37;
            app.ResetvaluesButton = uibutton(app.FalseColorTab, 'push');
            app.ResetvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetvaluesButtonPushed, true);
            app.ResetvaluesButton.Enable = 'off';
            app.ResetvaluesButton.Text = 'Reset values';
            app.ProcessButton = uibutton(app.FalseColorTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ContrastSwitchLabel = uilabel(app.FalseColorTab);
            app.ContrastSwitchLabel.Text = 'Contrast:';
            app.ContrastSwitch = uiswitch(app.FalseColorTab, 'slider');
            app.ContrastSwitch.ValueChangedFcn = createCallbackFcn(app, @ContrastSwitchValueChanged, true);
            app.CropImageTab = uitab(app.TabGroup);
            app.CropImageTab.Title = 'Crop Image';
            app.CropBlackWhiteImageButton = uibutton(app.CropImageTab, 'push');
            app.CropBlackWhiteImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropBlackWhiteImageButtonPushed, true);
            app.CropBlackWhiteImageButton.Text = {'Crop Black & White Image '; ''};
            app.CropColorImageButton = uibutton(app.CropImageTab, 'push');
            app.CropColorImageButton.ButtonPushedFcn = createCallbackFcn(app, @CropColorImageButtonPushed, true);
            app.CropColorImageButton.Text = {'Crop Color Image '; ''};
            app.CropInformationButton = uibutton(app.CropImageTab, 'push');
            app.CropInformationButton.ButtonPushedFcn = createCallbackFcn(app, @CropInformationButtonPushed, true);
            app.CropInformationButton.Text = {'Crop Information '; ''};
            app.TextArea = uitextarea(app.CropImageTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'To extract a rectangular portion of an image, use the Crop Black & White Button or Crop Color Image Button. You can specify the crop region interactively using the mouse in a figure window.'; ''; 'Click and drag the pointer to specify the size and position of the crop rectangle. You can move and adjust the size of the crop rectangle using the mouse. When you are satisfied with the crop rectangle, double-click to perform the crop operation, or right-click inside the crop rectangle and select Crop Image from the context menu. '; '                            © 1994-2021 The MathWorks, Inc.'};
            app.TextArea_2 = uitextarea(app.CropImageTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'To extract the image''s information portion of  NOAA image,'; 'use the Crop Information Button'};
            app.Image = uiimage(app.aplicacion2);
            app.Image.ImageSource = 'base2.jpg';
            % Show the figure after all components are created
            app.aplicacion2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Procesado(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.aplicacion2)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.aplicacion2)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.aplicacion2)
        end
    end
end