classdef Visualizacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        SaveImageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)
            imshow(img, 'Parent', app.UIAxes);
            
%             Get size of your original application UI:
            uisize = app.UIFigure.Position;
            screenWidth = uisize(3);
            screenHeight = uisize(4);
            left = screenWidth*0.1;
              bottom = screenHeight*0.1;
            %Assign new size for the width and height:
            width = screenWidth;
            height = screenHeight;
            
            app.UIFigure.Position = [left bottom width height]; 
            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.fig'};
            [file, path] = uiputfile(filter); %open a png file
            path = strcat(convertCharsToStrings(path),convertCharsToStrings(file));
            exportgraphics(app.UIAxes, path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'base3.jpg';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualizacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end